classdef manual_habituation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        habituation_gui    matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        Valve1DropButton   matlab.ui.control.Button
        Valve2DropButton   matlab.ui.control.Button
        Valve3DropButton   matlab.ui.control.Button
        Valve4DropButton   matlab.ui.control.Button
        Valve5DropButton   matlab.ui.control.Button
        Valve6DropButton   matlab.ui.control.Button
        Valve8DropButton   matlab.ui.control.Button
        Valve7DropButton   matlab.ui.control.Button
        CentralButton      matlab.ui.control.StateButton
        LateralButton      matlab.ui.control.StateButton
        AspiratorButton    matlab.ui.control.StateButton
        MoveDropDownLabel  matlab.ui.control.Label
        MoveDropDown       matlab.ui.control.DropDown
        LeftDropButton     matlab.ui.control.Button
        RightDropButton    matlab.ui.control.Button
        TextArea           matlab.ui.control.TextArea
        Title2             matlab.ui.control.TextArea
        Title3             matlab.ui.control.TextArea
        MessageCenter      matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        valveDuration = 0.25; % Duration valve is open for valve drop
        rewardDuration = 0.05; % Duration lateral valve is open for valve drop
        errorvalue = 0;
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.habituation_gui.Name = 'Manual Habituation';
        end

        % Button pushed function: Valve1DropButton
        function Valve1DropButtonPushed(app, event)
            OverrideValveModule(1,app.valveDuration);
            app.MessageCenter.Value = 'valve 1 drop';
        end

        % Button pushed function: Valve2DropButton
        function Valve2DropButtonPushed(app, event)
            OverrideValveModule(2,app.valveDuration);
            app.MessageCenter.Value = 'valve 2 drop';
        end

        % Button pushed function: Valve3DropButton
        function Valve3DropButtonPushed(app, event)
            OverrideValveModule(3,app.valveDuration);
            app.MessageCenter.Value = 'valve 3 drop';
        end

        % Button pushed function: Valve4DropButton
        function Valve4DropButtonPushed(app, event)
            OverrideValveModule(4,app.valveDuration);
            app.MessageCenter.Value = 'valve 4 drop';
        end

        % Button pushed function: Valve5DropButton
        function Valve5DropButtonPushed(app, event)
            OverrideValveModule(5,app.valveDuration);
            app.MessageCenter.Value = 'valve 5 drop';
        end

        % Button pushed function: Valve6DropButton
        function Valve6DropButtonPushed(app, event)
            OverrideValveModule(6,app.valveDuration);
            app.MessageCenter.Value = 'valve 6 drop';
        end

        % Button pushed function: Valve7DropButton
        function Valve7DropButtonPushed(app, event)
            OverrideValveModule(7,app.valveDuration);
            app.MessageCenter.Value = 'valve 7 drop';
        end

        % Button pushed function: Valve8DropButton
        function Valve8DropButtonPushed(app, event)
            OverrideValveModule(8,app.valveDuration);
            app.MessageCenter.Value = 'valve 8 drop';
        end

        % Value changed function: LateralButton
        function LateralButtonValueChanged(app, event)
            value = app.LateralButton.Value;
            value2 = app.CentralButton.Value;
            if value == 1
                if value2 == 1 % Don't move if central is forward
                    app.errorvalue = 1;
                    app.MessageCenter.Value = 'Crash imminent!!!';
                    app.MessageCenter.FontColor = [1 0 0];
                else
                    t_ZaberMotor_test(1,15);
                    app.LateralButton.BackgroundColor = 'y';
                    app.LateralButton.Text = 'Move Lateral DOWN';
                    app.MessageCenter.Value = 'lateral up';
                    app.MessageCenter.FontColor = [0 0 0];
                end
                

            else
                t_ZaberMotor_test(1,0);
                app.LateralButton.BackgroundColor = [1 1 1];
                app.LateralButton.Text = 'Move Lateral UP';
                app.MessageCenter.Value = 'lateral down';
                app.MessageCenter.FontColor = [0 0 0];
            end
        end

        % Value changed function: CentralButton
        function CentralButtonValueChanged(app, event)
            value = app.CentralButton.Value;
            value2 = app.LateralButton.Value;
            if value == 1
                if value2 == 1 % Don't move if lateral are up
                    app.errorvalue = 1;
                    app.MessageCenter.Value = 'Crash imminent!!!';
                    app.MessageCenter.FontColor = [1 0 0];
                else
                    if strcmp(app.MoveDropDown.Value,'Default')
                        t_ZaberMotor_test(2,22);
                    else
                        t_ZaberMotor_test(2,str2num(app.MoveDropDown.Value));
                    end
            
                app.CentralButton.BackgroundColor = 'y';
                app.CentralButton.Text = 'Move Central BACK';
                app.MessageCenter.Value = 'central foreward';
                app.MessageCenter.FontColor = [0 0 0];
                end
                
            else
                t_ZaberMotor_test(2,0);
                app.CentralButton.BackgroundColor = [1 1 1];
                app.CentralButton.Text = 'Move Central FORE';
                app.MessageCenter.Value = 'central back';
                app.MessageCenter.FontColor = [0 0 0];
            end
        end

        % Value changed function: AspiratorButton
        function AspiratorButtonValueChanged(app, event)
            value = app.AspiratorButton.Value;
            if value == 1
                t_ZaberMotor_test(3,0);
                app.AspiratorButton.BackgroundColor = 'y';
                app.AspiratorButton.Text = 'Move Aspirator DOWN';
            else
                t_ZaberMotor_test(3,10);
                app.AspiratorButton.BackgroundColor = [1 1 1];
                app.AspiratorButton.Text = 'Move Aspirator UP';
            end
        end

        % Button pushed function: LeftDropButton
        function LeftDropButtonPushed(app, event)
            OverrideStateMachine(1,app.rewardDuration);
        end

        % Button pushed function: RightDropButton
        function RightDropButtonPushed(app, event)
            OverrideStateMachine(2,app.rewardDuration);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.habituation_gui = uifigure('Visible', 'off');
            app.habituation_gui.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.habituation_gui);
            app.Valve1DropButton = uibutton(app.GridLayout, 'push');
            app.Valve1DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve1DropButtonPushed, true);
            app.Valve1DropButton.Text = 'Valve 1 - Drop';
            app.Valve2DropButton = uibutton(app.GridLayout, 'push');
            app.Valve2DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve2DropButtonPushed, true);
            app.Valve2DropButton.Text = 'Valve 2 - Drop';
            app.Valve3DropButton = uibutton(app.GridLayout, 'push');
            app.Valve3DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve3DropButtonPushed, true);
            app.Valve3DropButton.Text = 'Valve 3 - Drop';
            app.Valve4DropButton = uibutton(app.GridLayout, 'push');
            app.Valve4DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve4DropButtonPushed, true);
            app.Valve4DropButton.Text = 'Valve 4 - Drop';
            app.Valve5DropButton = uibutton(app.GridLayout, 'push');
            app.Valve5DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve5DropButtonPushed, true);
            app.Valve5DropButton.Text = 'Valve 5 - Drop';
            app.Valve6DropButton = uibutton(app.GridLayout, 'push');
            app.Valve6DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve6DropButtonPushed, true);
            app.Valve6DropButton.Text = 'Valve 6 - Drop';
            app.Valve8DropButton = uibutton(app.GridLayout, 'push');
            app.Valve8DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve8DropButtonPushed, true);
            app.Valve8DropButton.Text = 'Valve 8 - Drop';
            app.Valve7DropButton = uibutton(app.GridLayout, 'push');
            app.Valve7DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve7DropButtonPushed, true);
            app.Valve7DropButton.Text = 'Valve 7 - Drop';
            app.CentralButton = uibutton(app.GridLayout, 'state');
            app.CentralButton.ValueChangedFcn = createCallbackFcn(app, @CentralButtonValueChanged, true);
            app.CentralButton.Text = 'Central';
            app.LateralButton = uibutton(app.GridLayout, 'state');
            app.LateralButton.ValueChangedFcn = createCallbackFcn(app, @LateralButtonValueChanged, true);
            app.LateralButton.Text = 'Lateral';
            app.AspiratorButton = uibutton(app.GridLayout, 'state');
            app.AspiratorButton.ValueChangedFcn = createCallbackFcn(app, @AspiratorButtonValueChanged, true);
            app.AspiratorButton.Text = 'Aspirator';
            app.MoveDropDownLabel = uilabel(app.GridLayout);
            app.MoveDropDownLabel.Text = 'Move';
            app.MoveDropDown = uidropdown(app.GridLayout);
            app.MoveDropDown.Items = {'Default', '18', '19', '20', '21', '22', '23', '24', '25'};
            app.MoveDropDown.Value = 'Default';
            app.LeftDropButton = uibutton(app.GridLayout, 'push');
            app.LeftDropButton.ButtonPushedFcn = createCallbackFcn(app, @LeftDropButtonPushed, true);
            app.LeftDropButton.Text = 'Left - Drop';
            app.RightDropButton = uibutton(app.GridLayout, 'push');
            app.RightDropButton.ButtonPushedFcn = createCallbackFcn(app, @RightDropButtonPushed, true);
            app.RightDropButton.Text = 'Right - Drop';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Release Tastant Drop'};
            app.Title2 = uitextarea(app.GridLayout);
            app.Title2.Editable = 'off';
            app.Title2.Value = {'Release Lateral Drop'};
            app.Title3 = uitextarea(app.GridLayout);
            app.Title3.Editable = 'off';
            app.Title3.Value = {'Motor Controls'};
            app.MessageCenter = uitextarea(app.GridLayout);
            app.MessageCenter.Editable = 'off';
            app.MessageCenter.Value = {'Message Center'};
            % Show the figure after all components are created
            app.habituation_gui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manual_habituation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.habituation_gui)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.habituation_gui)
        end
    end
end