classdef manual_habituation2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        habituation_gui        matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        ManualHabituationTab   matlab.ui.container.Tab
        RightBottomDropButton  matlab.ui.control.Button
        LeftBottomdropButton   matlab.ui.control.Button
        Valve10DropButton      matlab.ui.control.Button
        Valve9DropButton       matlab.ui.control.Button
        MoveDropDown           matlab.ui.control.DropDown
        MoveDropDownLabel      matlab.ui.control.Label
        MessageCenter          matlab.ui.control.TextArea
        Title3                 matlab.ui.control.TextArea
        Title2                 matlab.ui.control.TextArea
        TextArea               matlab.ui.control.TextArea
        RightDropButton        matlab.ui.control.Button
        LeftDropButton         matlab.ui.control.Button
        AspiratorButton        matlab.ui.control.StateButton
        LateralButton          matlab.ui.control.StateButton
        CentralButton          matlab.ui.control.StateButton
        Valve7DropButton       matlab.ui.control.Button
        Valve8DropButton       matlab.ui.control.Button
        Valve6DropButton       matlab.ui.control.Button
        Valve5DropButton       matlab.ui.control.Button
        Valve4DropButton       matlab.ui.control.Button
        Valve3DropButton       matlab.ui.control.Button
        Valve2DropButton       matlab.ui.control.Button
        Valve1DropButton       matlab.ui.control.Button
        FillLinesTab           matlab.ui.container.Tab
        OpenValve8Button       matlab.ui.control.StateButton
        OpenValve7Button       matlab.ui.control.StateButton
        OpenValve6Button       matlab.ui.control.StateButton
        OpenValve5Button       matlab.ui.control.StateButton
        OpenValve4Button       matlab.ui.control.StateButton
        OpenValve3Button       matlab.ui.control.StateButton
        OpenValve2Button       matlab.ui.control.StateButton
        OpenValve1Button       matlab.ui.control.StateButton
        TextArea_2             matlab.ui.control.TextArea
        FillLines2Tab          matlab.ui.container.Tab
        OdorValve2Button       matlab.ui.control.StateButton
        OdorValve1Button       matlab.ui.control.StateButton
        TextArea_3             matlab.ui.control.TextArea
        OpenValve8Button_2     matlab.ui.control.StateButton
        OpenValve7Button_2     matlab.ui.control.StateButton
        OpenValve6Button_2     matlab.ui.control.StateButton
        OpenValve5Button_2     matlab.ui.control.StateButton
        OpenValve4Button_2     matlab.ui.control.StateButton
        OpenValve3Button_2     matlab.ui.control.StateButton
        OpenValve2Button_2     matlab.ui.control.StateButton
        OpenValve1Button_2     matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        valveDuration = 0.15; % Duration valve is open for valve drop
        rewardDuration = 0.07; % Duration lateral valve is open for valve drop
        errorvalue = 0;
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.habituation_gui.Name = 'Manual Habituation 2';
        end

        % Button pushed function: Valve1DropButton
        function Valve1DropButtonPushed(app, event)
            OverrideValveModule(1,app.valveDuration);
            app.MessageCenter.Value = 'valve 1 drop';
        end

        % Button pushed function: Valve2DropButton
        function Valve2DropButtonPushed(app, event)
            OverrideValveModule(2,app.valveDuration);
            app.MessageCenter.Value = 'valve 2 drop';
        end

        % Button pushed function: Valve3DropButton
        function Valve3DropButtonPushed(app, event)
            OverrideValveModule(3,app.valveDuration);
            app.MessageCenter.Value = 'valve 3 drop';
        end

        % Button pushed function: Valve4DropButton
        function Valve4DropButtonPushed(app, event)
            OverrideValveModule(4,app.valveDuration);
            app.MessageCenter.Value = 'valve 4 drop';
        end

        % Button pushed function: Valve5DropButton
        function Valve5DropButtonPushed(app, event)
            OverrideValveModule(5,app.valveDuration);
            app.MessageCenter.Value = 'valve 5 drop';
        end

        % Button pushed function: Valve6DropButton
        function Valve6DropButtonPushed(app, event)
            OverrideValveModule(6,app.valveDuration);
            app.MessageCenter.Value = 'valve 6 drop';
        end

        % Button pushed function: Valve7DropButton
        function Valve7DropButtonPushed(app, event)
            OverrideValveModule(7,app.valveDuration);
            app.MessageCenter.Value = 'valve 7 drop';
        end

        % Button pushed function: Valve8DropButton
        function Valve8DropButtonPushed(app, event)
            OverrideValveModule(8,app.valveDuration);
            app.MessageCenter.Value = 'valve 8 drop';
        end

        % Value changed function: LateralButton
        function LateralButtonValueChanged(app, event)
            value = app.LateralButton.Value;
            value2 = app.CentralButton.Value;
            if value == 1
                if value2 == 1 % Don't move if central is forward
                    app.errorvalue = 1;
                    app.MessageCenter.Value = 'Crash imminent!!!';
                    app.MessageCenter.FontColor = [1 0 0];
                else
                    t_ZaberMotor_test2(1,15);
                    app.LateralButton.BackgroundColor = 'y';
                    app.LateralButton.Text = 'Move Lateral DOWN';
                    app.MessageCenter.Value = 'lateral up';
                    app.MessageCenter.FontColor = [0 0 0];
                end
                

            else
                t_ZaberMotor_test2(1,0);
                app.LateralButton.BackgroundColor = [1 1 1];
                app.LateralButton.Text = 'Move Lateral UP';
                app.MessageCenter.Value = 'lateral down';
                app.MessageCenter.FontColor = [0 0 0];
            end
        end

        % Value changed function: CentralButton
        function CentralButtonValueChanged(app, event)
            value = app.CentralButton.Value;
            value2 = app.LateralButton.Value;
            if value == 1
                if value2 == 1 % Don't move if lateral are up
                    app.errorvalue = 1;
                    app.MessageCenter.Value = 'Crash imminent!!!';
                    app.MessageCenter.FontColor = [1 0 0];
                else
                    if strcmp(app.MoveDropDown.Value,'Default')
                        t_ZaberMotor_test2(2,18);
                    else
                        t_ZaberMotor_test2(2,str2num(app.MoveDropDown.Value));
                    end
            
                app.CentralButton.BackgroundColor = 'y';
                app.CentralButton.Text = 'Move Central BACK';
                app.MessageCenter.Value = 'central foreward';
                app.MessageCenter.FontColor = [0 0 0];
                end
                
            else
                t_ZaberMotor_test2(2,0);
                app.CentralButton.BackgroundColor = [1 1 1];
                app.CentralButton.Text = 'Move Central FORE';
                app.MessageCenter.Value = 'central back';
                app.MessageCenter.FontColor = [0 0 0];
            end
        end

        % Value changed function: AspiratorButton
        function AspiratorButtonValueChanged(app, event)
            value = app.AspiratorButton.Value;
            if value == 1
                t_ZaberMotor_test2(3,0);
                app.AspiratorButton.BackgroundColor = 'y';
                app.AspiratorButton.Text = 'Move Aspirator DOWN';
            else
                t_ZaberMotor_test2(3,10);
                app.AspiratorButton.BackgroundColor = [1 1 1];
                app.AspiratorButton.Text = 'Move Aspirator UP';
            end
        end

        % Button pushed function: LeftDropButton
        function LeftDropButtonPushed(app, event)
            OverrideStateMachine(1,app.rewardDuration);
        end

        % Button pushed function: RightDropButton
        function RightDropButtonPushed(app, event)
            OverrideStateMachine(2,app.rewardDuration);
        end

        % Value changed function: OpenValve1Button
        function OpenValve1ButtonValueChanged(app, event)
            value = app.OpenValve1Button.Value;
            if value == 1
                setBPODvalve(1,'O');
                app.OpenValve1Button.Text = 'Close Valve 1';
                app.OpenValve1Button.BackgroundColor = [0.96 0.70 0.38];
            else
                setBPODvalve(1,'C');
                app.OpenValve1Button.Text = 'Open Valve 1';
                app.OpenValve1Button.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OpenValve2Button
        function OpenValve2ButtonValueChanged(app, event)
            value = app.OpenValve2Button.Value;
            if value == 1
                setBPODvalve(2,'O');
                app.OpenValve2Button.Text = 'Close Valve 2';
                app.OpenValve2Button.BackgroundColor = [0.96 0.70 0.38];
            else
                setBPODvalve(2,'C');
                app.OpenValve2Button.Text = 'Open Valve 2';
                app.OpenValve2Button.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OpenValve3Button
        function OpenValve3ButtonValueChanged(app, event)
            value = app.OpenValve3Button.Value;
            if value == 1
                setBPODvalve(3,'O');
                app.OpenValve3Button.Text = 'Close Valve 3';
                app.OpenValve3Button.BackgroundColor = [0.96 0.70 0.38];
            else
                setBPODvalve(3,'C');
                app.OpenValve3Button.Text = 'Open Valve 3';
                app.OpenValve3Button.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OpenValve4Button
        function OpenValve4ButtonValueChanged(app, event)
            value = app.OpenValve4Button.Value;
            if value == 1
                setBPODvalve(4,'O');
                app.OpenValve4Button.Text = 'Close Valve 4';
                app.OpenValve4Button.BackgroundColor = [0.96 0.70 0.38];
            else
                setBPODvalve(4,'C');
                app.OpenValve4Button.Text = 'Open Valve 4';
                app.OpenValve4Button.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OpenValve5Button
        function OpenValve5ButtonValueChanged(app, event)
            value = app.OpenValve5Button.Value;
            if value == 1
                setBPODvalve(5,'O');
                app.OpenValve5Button.Text = 'Close Valve 5';
                app.OpenValve5Button.BackgroundColor = [0.96 0.70 0.38];
            else
                setBPODvalve(5,'C');
                app.OpenValve5Button.Text = 'Open Valve 5';
                app.OpenValve5Button.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OpenValve6Button
        function OpenValve6ButtonValueChanged(app, event)
            value = app.OpenValve6Button.Value;
            if value == 1
                setBPODvalve(6,'O');
                app.OpenValve6Button.Text = 'Close Valve 6';
                app.OpenValve6Button.BackgroundColor = [0.96 0.70 0.38];
            else
                setBPODvalve(6,'C');
                app.OpenValve6Button.Text = 'Open Valve 6';
                app.OpenValve6Button.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OpenValve7Button
        function OpenValve7ButtonValueChanged(app, event)
            value = app.OpenValve7Button.Value;
            if value == 1
                setBPODvalve(7,'O');
                app.OpenValve7Button.Text = 'Close Valve 7';
                app.OpenValve7Button.BackgroundColor = [0.96 0.70 0.38];
            else
                setBPODvalve(7,'C');
                app.OpenValve7Button.Text = 'Open Valve 7';
                app.OpenValve7Button.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OpenValve8Button
        function OpenValve8ButtonValueChanged(app, event)
            value = app.OpenValve8Button.Value;
            if value == 1
                setBPODvalve(8,'O');
                app.OpenValve8Button.Text = 'Close Valve 8';
                app.OpenValve8Button.BackgroundColor = [0.96 0.70 0.38];
            else
                setBPODvalve(8,'C');
                app.OpenValve8Button.Text = 'Open Valve 8';
                app.OpenValve8Button.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OpenValve8Button_2
        function OpenValve8Button_2ValueChanged(app, event)
            value = app.OpenValve8Button_2.Value;
                if value == 1
                setBPODvalve2(8,'O');
                app.OpenValve8Button_2.Text = 'Close Valve 8';
                app.OpenValve8Button_2.BackgroundColor = [0.96 0.70 0.38];
            else
                setBPODvalve2(8,'C');
                app.OpenValve8Button_2.Text = 'Open Valve 8';
                app.OpenValve8Button_2.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OpenValve7Button_2
        function OpenValve7Button_2ValueChanged(app, event)
            value = app.OpenValve7Button_2.Value;
             if value == 1
                setBPODvalve2(7,'O');
                app.OpenValve7Button_2.Text = 'Close Valve 7';
                app.OpenValve7Button_2.BackgroundColor = [0.96 0.70 0.38];
            else
                setBPODvalve2(7,'C');
                app.OpenValve7Button_2.Text = 'Open Valve 7';
                app.OpenValve7Button_2.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OpenValve6Button_2
        function OpenValve6Button_2ValueChanged(app, event)
            value = app.OpenValve6Button_2.Value;
             if value == 1
                setBPODvalve2(6,'O');
                app.OpenValve6Button_2.Text = 'Close Valve 6';
                app.OpenValve6Button_2.BackgroundColor = [0.96 0.70 0.38];
            else
                setBPODvalve2(6,'C');
                app.OpenValve6Button_2.Text = 'Open Valve 6';
                app.OpenValve6Button_2.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OpenValve5Button_2
        function OpenValve5Button_2ValueChanged(app, event)
            value = app.OpenValve5Button_2.Value;
             if value == 1
                setBPODvalve2(5,'O');
                app.OpenValve5Button_2.Text = 'Close Valve 5';
                app.OpenValve5Button_2.BackgroundColor = [0.96 0.70 0.38];
            else
                setBPODvalve2(5,'C');
                app.OpenValve5Button_2.Text = 'Open Valve 5';
                app.OpenValve5Button_2.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OpenValve4Button_2
        function OpenValve4Button_2ValueChanged(app, event)
            value = app.OpenValve4Button_2.Value;
             if value == 1
                setBPODvalve2(4,'O');
                app.OpenValve4Button_2.Text = 'Close Valve 4';
                app.OpenValve4Button_2.BackgroundColor = [0.96 0.70 0.38];
            else
                setBPODvalve2(4,'C');
                app.OpenValve4Button_2.Text = 'Open Valve 4';
                app.OpenValve4Button_2.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OpenValve3Button_2
        function OpenValve3Button_2ValueChanged(app, event)
            value = app.OpenValve3Button_2.Value;
             if value == 1
                setBPODvalve2(3,'O');
                app.OpenValve3Button_2.Text = 'Close Valve 3';
                app.OpenValve3Button_2.BackgroundColor = [0.96 0.70 0.38];
            else
                setBPODvalve2(3,'C');
                app.OpenValve3Button_2.Text = 'Open Valve 3';
                app.OpenValve3Button_2.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OpenValve2Button_2
        function OpenValve2Button_2ValueChanged(app, event)
            value = app.OpenValve2Button_2.Value;
             if value == 1
                setBPODvalve2(2,'O');
                app.OpenValve2Button_2.Text = 'Close Valve 2';
                app.OpenValve2Button_2.BackgroundColor = [0.96 0.70 0.38];
            else
                setBPODvalve2(2,'C');
                app.OpenValve2Button_2.Text = 'Open Valve 2';
                app.OpenValve2Button_2.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OpenValve1Button_2
        function OpenValve1Button_2ValueChanged(app, event)
            value = app.OpenValve1Button_2.Value;
             if value == 1
                setBPODvalve2(1,'O');
                app.OpenValve1Button_2.Text = 'Close Valve 1';
                app.OpenValve1Button_2.BackgroundColor = [0.96 0.70 0.38];
             else
                setBPODvalve2(1,'C');
                app.OpenValve1Button_2.Text = 'Open Valve 1';
                app.OpenValve1Button_2.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Button pushed function: Valve9DropButton
        function Valve9DropButtonPushed(app, event)
              OverrideValveModule2(7,app.valveDuration);
            app.MessageCenter.Value = 'valve 9 drop';
        end

        % Button pushed function: Valve10DropButton
        function Valve10DropButtonPushed(app, event)
              OverrideValveModule2(6,app.valveDuration);
            app.MessageCenter.Value = 'valve 10 drop';
        end

        % Value changed function: OdorValve1Button
        function OdorValve1ButtonValueChanged(app, event)
            value = app.OdorValve1Button.Value;
             if value == 1
                setBPODvalve2(8,'O');
                setBPODvalve2(5,'O');
                setBPODvalve2(1,'O');

                app.OdorValve1Button.Text = 'Close Odor 1';
                app.OdorValve1Button.BackgroundColor = [0.96 0.70 0.38];
             else
                setBPODvalve2(8,'C');
                setBPODvalve2(5,'C');
                setBPODvalve2(1,'C');

                app.OdorValve1Button.Text = 'Open Odor 1';
                app.OdorValve1Button.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Value changed function: OdorValve2Button
        function OdorValve2ButtonValueChanged(app, event)
            value = app.OdorValve2Button.Value;
            if value == 1
                setBPODvalve2(8,'O');
                setBPODvalve2(5,'O');
                setBPODvalve2(2,'O');

                app.OdorValve2Button.Text = 'Close Odor 2';
                app.OdorValve2Button.BackgroundColor = [0.96 0.70 0.38];
             else
                setBPODvalve2(8,'C');
                setBPODvalve2(5,'C');
                setBPODvalve2(2,'C');

                app.OdorValve2Button.Text = 'Open Odor 2';
                app.OdorValve2Button.BackgroundColor = [0.60,0.98,0.79];
            end
        end

        % Button pushed function: LeftBottomdropButton
        function LeftBottomdropButtonPushed(app, event)
            OverrideStateMachine(4,app.rewardDuration);
        end

        % Button pushed function: RightBottomDropButton
        function RightBottomDropButtonPushed(app, event)
            OverrideStateMachine(8,app.rewardDuration);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.habituation_gui = uifigure('Visible', 'off');
            app.habituation_gui.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.habituation_gui);
            app.ManualHabituationTab = uitab(app.TabGroup);
            app.ManualHabituationTab.Title = 'Manual Habituation';
            app.Valve1DropButton = uibutton(app.ManualHabituationTab, 'push');
            app.Valve1DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve1DropButtonPushed, true);
            app.Valve1DropButton.Text = 'Valve 1 - Drop';
            app.Valve2DropButton = uibutton(app.ManualHabituationTab, 'push');
            app.Valve2DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve2DropButtonPushed, true);
            app.Valve2DropButton.Text = 'Valve 2 - Drop';
            app.Valve3DropButton = uibutton(app.ManualHabituationTab, 'push');
            app.Valve3DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve3DropButtonPushed, true);
            app.Valve3DropButton.Text = 'Valve 3 - Drop';
            app.Valve4DropButton = uibutton(app.ManualHabituationTab, 'push');
            app.Valve4DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve4DropButtonPushed, true);
            app.Valve4DropButton.Text = 'Valve 4 - Drop';
            app.Valve5DropButton = uibutton(app.ManualHabituationTab, 'push');
            app.Valve5DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve5DropButtonPushed, true);
            app.Valve5DropButton.Text = 'Valve 5 - Drop';
            app.Valve6DropButton = uibutton(app.ManualHabituationTab, 'push');
            app.Valve6DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve6DropButtonPushed, true);
            app.Valve6DropButton.Text = 'Valve 6 - Drop';
            app.Valve8DropButton = uibutton(app.ManualHabituationTab, 'push');
            app.Valve8DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve8DropButtonPushed, true);
            app.Valve8DropButton.Text = 'Valve 8 - Drop';
            app.Valve7DropButton = uibutton(app.ManualHabituationTab, 'push');
            app.Valve7DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve7DropButtonPushed, true);
            app.Valve7DropButton.Text = 'Valve 7 - Drop';
            app.CentralButton = uibutton(app.ManualHabituationTab, 'state');
            app.CentralButton.ValueChangedFcn = createCallbackFcn(app, @CentralButtonValueChanged, true);
            app.CentralButton.Text = 'Central';
            app.LateralButton = uibutton(app.ManualHabituationTab, 'state');
            app.LateralButton.ValueChangedFcn = createCallbackFcn(app, @LateralButtonValueChanged, true);
            app.LateralButton.Text = 'Lateral';
            app.AspiratorButton = uibutton(app.ManualHabituationTab, 'state');
            app.AspiratorButton.ValueChangedFcn = createCallbackFcn(app, @AspiratorButtonValueChanged, true);
            app.AspiratorButton.Text = 'Aspirator';
            app.LeftDropButton = uibutton(app.ManualHabituationTab, 'push');
            app.LeftDropButton.ButtonPushedFcn = createCallbackFcn(app, @LeftDropButtonPushed, true);
            app.LeftDropButton.Text = 'Left - Drop';
            app.RightDropButton = uibutton(app.ManualHabituationTab, 'push');
            app.RightDropButton.ButtonPushedFcn = createCallbackFcn(app, @RightDropButtonPushed, true);
            app.RightDropButton.Text = 'Right - Drop';
            app.TextArea = uitextarea(app.ManualHabituationTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Release Tastant Drop'};
            app.Title2 = uitextarea(app.ManualHabituationTab);
            app.Title2.Editable = 'off';
            app.Title2.Value = {'Release Lateral Drop'};
            app.Title3 = uitextarea(app.ManualHabituationTab);
            app.Title3.Editable = 'off';
            app.Title3.Value = {'Motor Controls'};
            app.MessageCenter = uitextarea(app.ManualHabituationTab);
            app.MessageCenter.Editable = 'off';
            app.MessageCenter.Value = {'Message Center'};
            app.MoveDropDownLabel = uilabel(app.ManualHabituationTab);
            app.MoveDropDownLabel.Text = 'Move';
            app.MoveDropDown = uidropdown(app.ManualHabituationTab);
            app.MoveDropDown.Items = {'Default', '18', '19', '20', '21', '22', '23', '24', '25'};
            app.MoveDropDown.Value = 'Default';
            app.Valve9DropButton = uibutton(app.ManualHabituationTab, 'push');
            app.Valve9DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve9DropButtonPushed, true);
            app.Valve9DropButton.Text = 'Valve 9 - Drop';
            app.Valve10DropButton = uibutton(app.ManualHabituationTab, 'push');
            app.Valve10DropButton.ButtonPushedFcn = createCallbackFcn(app, @Valve10DropButtonPushed, true);
            app.Valve10DropButton.Text = 'Valve 10 -Drop';
            app.LeftBottomdropButton = uibutton(app.ManualHabituationTab, 'push');
            app.LeftBottomdropButton.ButtonPushedFcn = createCallbackFcn(app, @LeftBottomdropButtonPushed, true);
            app.LeftBottomdropButton.Text = 'Left Bottom drop';
            app.RightBottomDropButton = uibutton(app.ManualHabituationTab, 'push');
            app.RightBottomDropButton.ButtonPushedFcn = createCallbackFcn(app, @RightBottomDropButtonPushed, true);
            app.RightBottomDropButton.Text = 'Right Bottom Drop';
            app.FillLinesTab = uitab(app.TabGroup);
            app.FillLinesTab.Title = 'Fill Lines';
            app.TextArea_2 = uitextarea(app.FillLinesTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Central valves'};
            app.OpenValve1Button = uibutton(app.FillLinesTab, 'state');
            app.OpenValve1Button.ValueChangedFcn = createCallbackFcn(app, @OpenValve1ButtonValueChanged, true);
            app.OpenValve1Button.Text = 'Open Valve 1';
            app.OpenValve2Button = uibutton(app.FillLinesTab, 'state');
            app.OpenValve2Button.ValueChangedFcn = createCallbackFcn(app, @OpenValve2ButtonValueChanged, true);
            app.OpenValve2Button.Text = 'Open Valve 2';
            app.OpenValve3Button = uibutton(app.FillLinesTab, 'state');
            app.OpenValve3Button.ValueChangedFcn = createCallbackFcn(app, @OpenValve3ButtonValueChanged, true);
            app.OpenValve3Button.Text = 'Open Valve 3';
            app.OpenValve4Button = uibutton(app.FillLinesTab, 'state');
            app.OpenValve4Button.ValueChangedFcn = createCallbackFcn(app, @OpenValve4ButtonValueChanged, true);
            app.OpenValve4Button.Text = 'Open Valve 4';
            app.OpenValve5Button = uibutton(app.FillLinesTab, 'state');
            app.OpenValve5Button.ValueChangedFcn = createCallbackFcn(app, @OpenValve5ButtonValueChanged, true);
            app.OpenValve5Button.Text = 'Open Valve 5';
            app.OpenValve6Button = uibutton(app.FillLinesTab, 'state');
            app.OpenValve6Button.ValueChangedFcn = createCallbackFcn(app, @OpenValve6ButtonValueChanged, true);
            app.OpenValve6Button.Text = 'Open Valve 6';
            app.OpenValve7Button = uibutton(app.FillLinesTab, 'state');
            app.OpenValve7Button.ValueChangedFcn = createCallbackFcn(app, @OpenValve7ButtonValueChanged, true);
            app.OpenValve7Button.Text = 'Open Valve 7';
            app.OpenValve8Button = uibutton(app.FillLinesTab, 'state');
            app.OpenValve8Button.ValueChangedFcn = createCallbackFcn(app, @OpenValve8ButtonValueChanged, true);
            app.OpenValve8Button.Text = 'Open Valve 8';
            app.FillLines2Tab = uitab(app.TabGroup);
            app.FillLines2Tab.Title = 'Fill Lines 2';
            app.OpenValve1Button_2 = uibutton(app.FillLines2Tab, 'state');
            app.OpenValve1Button_2.ValueChangedFcn = createCallbackFcn(app, @OpenValve1Button_2ValueChanged, true);
            app.OpenValve1Button_2.Text = 'Open Valve 1';
            app.OpenValve2Button_2 = uibutton(app.FillLines2Tab, 'state');
            app.OpenValve2Button_2.ValueChangedFcn = createCallbackFcn(app, @OpenValve2Button_2ValueChanged, true);
            app.OpenValve2Button_2.Text = 'Open Valve 2';
            app.OpenValve3Button_2 = uibutton(app.FillLines2Tab, 'state');
            app.OpenValve3Button_2.ValueChangedFcn = createCallbackFcn(app, @OpenValve3Button_2ValueChanged, true);
            app.OpenValve3Button_2.Text = 'Open Valve 3';
            app.OpenValve4Button_2 = uibutton(app.FillLines2Tab, 'state');
            app.OpenValve4Button_2.ValueChangedFcn = createCallbackFcn(app, @OpenValve4Button_2ValueChanged, true);
            app.OpenValve4Button_2.Text = 'Open Valve 4';
            app.OpenValve5Button_2 = uibutton(app.FillLines2Tab, 'state');
            app.OpenValve5Button_2.ValueChangedFcn = createCallbackFcn(app, @OpenValve5Button_2ValueChanged, true);
            app.OpenValve5Button_2.Text = 'Open Valve 5';
            app.OpenValve6Button_2 = uibutton(app.FillLines2Tab, 'state');
            app.OpenValve6Button_2.ValueChangedFcn = createCallbackFcn(app, @OpenValve6Button_2ValueChanged, true);
            app.OpenValve6Button_2.Text = 'Open Valve 6';
            app.OpenValve7Button_2 = uibutton(app.FillLines2Tab, 'state');
            app.OpenValve7Button_2.ValueChangedFcn = createCallbackFcn(app, @OpenValve7Button_2ValueChanged, true);
            app.OpenValve7Button_2.Text = 'Open Valve 7';
            app.OpenValve8Button_2 = uibutton(app.FillLines2Tab, 'state');
            app.OpenValve8Button_2.ValueChangedFcn = createCallbackFcn(app, @OpenValve8Button_2ValueChanged, true);
            app.OpenValve8Button_2.Text = 'Open Valve 8';
            app.TextArea_3 = uitextarea(app.FillLines2Tab);
            app.TextArea_3.Value = {'Valve Module 2'};
            app.OdorValve1Button = uibutton(app.FillLines2Tab, 'state');
            app.OdorValve1Button.ValueChangedFcn = createCallbackFcn(app, @OdorValve1ButtonValueChanged, true);
            app.OdorValve1Button.Text = 'Odor Valve 1';
            app.OdorValve2Button = uibutton(app.FillLines2Tab, 'state');
            app.OdorValve2Button.ValueChangedFcn = createCallbackFcn(app, @OdorValve2ButtonValueChanged, true);
            app.OdorValve2Button.Text = 'Odor Valve 2';
            % Show the figure after all components are created
            app.habituation_gui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manual_habituation2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.habituation_gui)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.habituation_gui)
        end
    end
end