classdef EventPulseNP_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CAMERAPanel                 matlab.ui.container.Panel
        StartCameraButton           matlab.ui.control.StateButton
        InitializeButton            matlab.ui.control.Button
        MANUALEVENTPULSEPanel       matlab.ui.container.Panel
        PulsedurationDropDown       matlab.ui.control.DropDown
        PulsedurationDropDownLabel  matlab.ui.control.Label
        EventPulseButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        a; % Description
    end
    
    
    methods (Access = public)
        
        function InitArduino(app)
            app.a = arduino();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Neuropixel Event Pulse';
        end

        % Button pushed function: EventPulseButton
        function EventPulseButtonPushed(app, event)
            sma = NewStateMachine();
            sma = AddState(sma, 'Name', 'ValveOpen','Timer', str2num(app.PulsedurationDropDown.Value),...
            'StateChangeConditions', {'Tup', '>exit'},'OutputActions', {'BNCState',1});

            SendStateMachine(sma);
            RawEvents = RunStateMachine;
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            InitArduino(app);
            app.InitializeButton.Text = 'Active!';
        end

        % Value changed function: StartCameraButton
        function StartCameraButtonValueChanged(app, event)
            value = app.StartCameraButton.Value;
            if value == 1
                writeDigitalPin(app.a,'D13',1);
                app.StartCameraButton.Text = 'Stop Camera';
                app.StartCameraButton.BackgroundColor = [1 0.29 0.21];
            else
                writeDigitalPin(app.a,'D13',0);
                app.StartCameraButton.Text = 'Start Camera';
                app.StartCameraButton.BackgroundColor = [0.54 0.82 0.18];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MANUALEVENTPULSEPanel = uipanel(app.UIFigure);
            app.MANUALEVENTPULSEPanel.Title = 'MANUAL EVENT PULSE';
            app.EventPulseButton = uibutton(app.MANUALEVENTPULSEPanel, 'push');
            app.EventPulseButton.ButtonPushedFcn = createCallbackFcn(app, @EventPulseButtonPushed, true);
            app.EventPulseButton.Text = 'Event Pulse';
            app.PulsedurationDropDownLabel = uilabel(app.MANUALEVENTPULSEPanel);
            app.PulsedurationDropDownLabel.Text = 'Pulse duration';
            app.PulsedurationDropDown = uidropdown(app.MANUALEVENTPULSEPanel);
            app.PulsedurationDropDown.Items = {'0.05', '0.1', '0.2', '0.3', '0.4', '0.5'};
            app.PulsedurationDropDown.Value = '0.05';
            app.CAMERAPanel = uipanel(app.UIFigure);
            app.CAMERAPanel.Title = 'CAMERA';
            app.InitializeButton = uibutton(app.CAMERAPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.StartCameraButton = uibutton(app.CAMERAPanel, 'state');
            app.StartCameraButton.ValueChangedFcn = createCallbackFcn(app, @StartCameraButtonValueChanged, true);
            app.StartCameraButton.Text = 'Start Camera';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EventPulseNP_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end